classdef FitPFCurves < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		PFCurveFitterUIFigure    matlab.ui.Figure
		GridLayout               matlab.ui.container.GridLayout
		LeftPanel                matlab.ui.container.Panel
		GridLayout2              matlab.ui.container.GridLayout
		FixGammaEditFieldLabel   matlab.ui.control.Label
		FixGammaEditField        matlab.ui.control.NumericEditField
		FixGammaCheckBox         matlab.ui.control.CheckBox
		FittingDropDown          matlab.ui.control.DropDown
		FittingDropDownLabel     matlab.ui.control.Label
		ResultsArea              matlab.ui.control.TextArea
		exportButton             matlab.ui.control.Button
		FixLambdaEditFieldLabel  matlab.ui.control.Label
		FixLambdaEditField       matlab.ui.control.NumericEditField
		GrainEditFieldLabel      matlab.ui.control.Label
		GrainEditField           matlab.ui.control.NumericEditField
		ParametricDropDownLabel  matlab.ui.control.Label
		ParametricDropDown       matlab.ui.control.DropDown
		EditField                matlab.ui.control.EditField
		ChooseFileButton         matlab.ui.control.Button
		UseExclusionCheckBox     matlab.ui.control.CheckBox
		LogSlopeCheckBox         matlab.ui.control.CheckBox
		RunButton                matlab.ui.control.Button
		LogAxisCheckBox          matlab.ui.control.CheckBox
		RightPanel               matlab.ui.container.Panel
		UIAxes                   matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = public)
		outvals % ui valuse to pass to analysis
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: ChooseFileButton
		function ChooseFileButtonPushed(app, event)
			[file, path] = uigetfile;
			if file == 0; app.outvals.file = []; app.outvals.path = []; return; end
			cd(path);
			app.ResultsArea.Value = '';
			cla(app.UIAxes);
			app.EditField.Value = file;
			app.outvals.file = file;
			app.outvals.path = path;
			app.PFCurveFitterUIFigure.Name = ['PF Curve: ' file];
		end

		% Button pushed function: RunButton
		function RunButtonPushed(app, event)
			app.ResultsArea.Value = 'Please wait...';
			cla(app.UIAxes);
			title(app.UIAxes,'Please wait...');
			drawnow;
			if ~isfield(app.outvals,'file') || isempty(app.outvals.file)
				app.ChooseFileButtonPushed();
			end
			if strcmpi(app.ParametricDropDown.Value,'Parametric')
				app.outvals.parametric = true;
			elseif strcmpi(app.ParametricDropDown.Value,'Nonparametric')
				app.outvals.parametric = false;
			else
				app.outvals.parametric = NaN;
			end
			app.outvals.fitting = app.FittingDropDown.Value;
			app.outvals.exclusion = app.UseExclusionCheckBox.Value;
			app.outvals.logSlope = app.LogSlopeCheckBox.Value;
			app.outvals.logAxis = app.LogAxisCheckBox.Value;
			app.outvals.grain = app.GrainEditField.Value;
			app.outvals.correctGamma = app.FixGammaCheckBox.Value;
			if app.FixGammaEditField.Value < 0
				app.outvals.fixedGamma = NaN;
			else
				app.outvals.fixedGamma = app.FixGammaEditField.Value;
			end
			if app.FixLambdaEditField.Value < 0
				app.outvals.fixedLambda = NaN;
			else
				app.outvals.fixedLambda = app.FixLambdaEditField.Value;
			end
			app.outvals.axis = app.UIAxes;
			app.outvals.results = app.ResultsArea;
			FitPFCurvesAnalysis(app.outvals);

		end

		% Button pushed function: exportButton
		function exportButtonPushed(app, event)
			f=figure('Units','normalized','Position',[0 0 0.6 0.5]);
			copyobj(app.UIAxes,f);
			set(gca,'Units','normalized','Position',[0 0 1 1]);
			text(gca,0.05,0.1,0,app.ResultsArea.Value);
			copygraphics(gca,'ContentType','vector');
		end

		% Value changed function: LogAxisCheckBox
		function LogAxisCheckBoxValueChanged(app, event)
			value = app.LogAxisCheckBox.Value;
			if value
				app.UIAxes.XScale = 'log';
			else
				app.UIAxes.XScale = 'linear';
			end
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.PFCurveFitterUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {528, 528};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {221, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.PFCurveFitterUIFigure = uifigure('Visible', 'off');
			app.PFCurveFitterUIFigure.Name = 'PF Curve Fitter';
			app.PFCurveFitterUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.PFCurveFitterUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout2 = uigridlayout(app.LeftPanel);
			app.LogAxisCheckBox = uicheckbox(app.GridLayout2);
			app.LogAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogAxisCheckBoxValueChanged, true);
			app.LogAxisCheckBox.Text = 'Log Axis?';
			app.RunButton = uibutton(app.GridLayout2, 'push');
			app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.RunButton.Text = 'Run';
			app.LogSlopeCheckBox = uicheckbox(app.GridLayout2);
			app.LogSlopeCheckBox.Text = 'Log Slope?';
			app.LogSlopeCheckBox.Value = true;
			app.UseExclusionCheckBox = uicheckbox(app.GridLayout2);
			app.UseExclusionCheckBox.Text = 'Use Exclusion?';
			app.ChooseFileButton = uibutton(app.GridLayout2, 'push');
			app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
			app.ChooseFileButton.Text = 'Choose File';
			app.EditField = uieditfield(app.GridLayout2, 'text');
			app.ParametricDropDown = uidropdown(app.GridLayout2);
			app.ParametricDropDown.Items = {'Ignore', 'Parametric', 'Nonparametric'};
			app.ParametricDropDown.Value = 'Ignore';
			app.ParametricDropDownLabel = uilabel(app.GridLayout2);
			app.ParametricDropDownLabel.Text = 'Parametric';
			app.GrainEditField = uieditfield(app.GridLayout2, 'numeric');
			app.GrainEditField.Limits = [10 10000];
			app.GrainEditField.RoundFractionalValues = 'on';
			app.GrainEditField.Value = 100;
			app.GrainEditFieldLabel = uilabel(app.GridLayout2);
			app.GrainEditFieldLabel.Text = 'Grain';
			app.FixLambdaEditField = uieditfield(app.GridLayout2, 'numeric');
			app.FixLambdaEditField.Value = -1;
			app.FixLambdaEditFieldLabel = uilabel(app.GridLayout2);
			app.FixLambdaEditFieldLabel.Text = 'Fix Lambda?';
			app.exportButton = uibutton(app.GridLayout2, 'push');
			app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
			app.exportButton.Text = 'export';
			app.ResultsArea = uitextarea(app.GridLayout2);
			app.FittingDropDownLabel = uilabel(app.GridLayout2);
			app.FittingDropDownLabel.Text = 'Fitting';
			app.FittingDropDown = uidropdown(app.GridLayout2);
			app.FittingDropDown.Items = {'nAPLE', 'jAPLE'};
			app.FittingDropDown.Value = 'jAPLE';
			app.FixGammaCheckBox = uicheckbox(app.GridLayout2);
			app.FixGammaCheckBox.Text = 'Fix Gamma?';
			app.FixGammaCheckBox.Value = true;
			app.FixGammaEditField = uieditfield(app.GridLayout2, 'numeric');
			app.FixGammaEditField.Value = -1;
			app.FixGammaEditFieldLabel = uilabel(app.GridLayout2);
			app.FixGammaEditFieldLabel.Text = 'Fix Gamma?';
			app.RightPanel = uipanel(app.GridLayout);
			app.UIAxes = uiaxes(app.RightPanel);
			title(app.UIAxes, 'Psychometric Function')
			xlabel(app.UIAxes, 'Contrast')
			ylabel(app.UIAxes, 'Probability Correct')
			zlabel(app.UIAxes, 'Z')
			% Show the figure after all components are created
			app.PFCurveFitterUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = FitPFCurves

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.PFCurveFitterUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.PFCurveFitterUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.PFCurveFitterUIFigure)
		end
	end
end