classdef basicTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasicTrainingAppUIFigure       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SettingsTab                    matlab.ui.container.Tab
        OptionsPanel                   matlab.ui.container.Panel
        StimulusSizedegEditFieldLabel  matlab.ui.control.Label
        StimulusSizedegEditField       matlab.ui.control.NumericEditField
        RewardatstartCheckBox          matlab.ui.control.CheckBox
        RewardduringCheckBox           matlab.ui.control.CheckBox
        RewardatendCheckBox            matlab.ui.control.CheckBox
        PlaytimesEditFieldLabel        matlab.ui.control.Label
        PlaytimesEditField             matlab.ui.control.NumericEditField
        HTML                           matlab.ui.control.HTML
        SPButton                       matlab.ui.control.Button
        InfoEditFieldLabel             matlab.ui.control.Label
        InfoEditField                  matlab.ui.control.EditField
        SubjectEditFieldLabel          matlab.ui.control.Label
        SubjectEditField               matlab.ui.control.EditField
        SavePathEditFieldLabel         matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        GOButton                       matlab.ui.control.Button
        DistanceEditFieldLabel         matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel      matlab.ui.control.Label
        PixelsPerCmEditField           matlab.ui.control.NumericEditField
        StimulusDropDownLabel          matlab.ui.control.Label
        StimulusDropDown               matlab.ui.control.DropDown
        SP2Button                      matlab.ui.control.Button
        ImagesEditFieldLabel           matlab.ui.control.Label
        ImagesEditField                matlab.ui.control.EditField
        RandomPositionCheckBox         matlab.ui.control.CheckBox
        PositionsEditFieldLabel        matlab.ui.control.Label
        PositionsEditField             matlab.ui.control.EditField
        ITIsEditFieldLabel             matlab.ui.control.Label
        ITIsEditField                  matlab.ui.control.NumericEditField
        TimeOutsEditFieldLabel         matlab.ui.control.Label
        TimeOutsEditField              matlab.ui.control.NumericEditField
        DebugModeCheckBox              matlab.ui.control.CheckBox
        EyetrackersettingsPanel        matlab.ui.container.Panel
        StrictEditFieldLabel           matlab.ui.control.Label
        StrictEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        InitTimeEditFieldLabel         matlab.ui.control.Label
        InitTimeEditField              matlab.ui.control.NumericEditField
        FixTimeEditFieldLabel          matlab.ui.control.Label
        FixTimeEditField               matlab.ui.control.NumericEditField
        TrackerDropDownLabel           matlab.ui.control.Label
        TrackerDropDown                matlab.ui.control.DropDown
        TrackingmodeDropDownLabel      matlab.ui.control.Label
        TrackingmodeDropDown           matlab.ui.control.DropDown
        CalPositionsEditFieldLabel     matlab.ui.control.Label
        CalPositionsEditField          matlab.ui.control.EditField
        CalibrationstimulusDropDownLabel  matlab.ui.control.Label
        CalibrationstimulusDropDown    matlab.ui.control.DropDown
        ValPositionsEditFieldLabel     matlab.ui.control.Label
        ValPositionsEditField          matlab.ui.control.EditField
        AutoPaceCheckBox               matlab.ui.control.CheckBox
        SamplerateKnobLabel            matlab.ui.control.Label
        SamplerateKnob                 matlab.ui.control.DiscreteKnob
        UseEyeTrackerCheckBox          matlab.ui.control.CheckBox
        DummyModeCheckBox              matlab.ui.control.CheckBox
        DrawEyePositionCheckBox        matlab.ui.control.CheckBox
        CalButton                      matlab.ui.control.Button
        CalibrationEditFieldLabel      matlab.ui.control.Label
        CalibrationEditField           matlab.ui.control.EditField
        PaceDurationEditField          matlab.ui.control.NumericEditField
        SpotSizeEditFieldLabel         matlab.ui.control.Label
        SpotSizeEditField              matlab.ui.control.NumericEditField
        PlotsTab                       matlab.ui.container.Tab
        ResultsPanel                   matlab.ui.container.Panel
        PlotAxis1                      matlab.ui.control.UIAxes
        PlotAxis2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ana % UI state passed to matlab
    end
    
    methods (Access = private)
        
        function parseSettings(app)
           app.ana = [];
           app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
           app.ana.subject = app.SubjectEditField.Value;
           app.ana.comments = app.InfoEditField.Value;
           app.ana.ResultDir = app.SavePathEditField.Value;
           app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
           app.ana.distance = app.DistanceEditField.Value;
           app.ana.size = app.StimulusSizedegEditField.Value;
           app.ana.playTimes = app.PlaytimesEditField.Value;
           app.ana.ITI = app.ITIsEditField.Value;
           app.ana.timeOut = app.TimeOutsEditField.Value;
           app.ana.rewardStart = logical(app.RewardatstartCheckBox.Value);
           app.ana.rewardDuring = logical(app.RewardduringCheckBox.Value);
           app.ana.rewardEnd = logical(app.RewardatendCheckBox.Value);
           app.ana.stimulus = app.StimulusDropDown.Value;
           app.ana.imageDir = app.ImagesEditField.Value;
           app.ana.positions = str2num(app.PositionsEditField.Value);
           app.ana.randomPosition = logical(app.RandomPositionCheckBox.Value);
           
           app.ana.useTracker = logical(app.UseEyeTrackerCheckBox.Value);
           app.ana.isDummy = logical(app.DummyModeCheckBox.Value);
           
           app.ana.tracker = app.TrackerDropDown.Value;
           app.ana.trackingMode = app.TrackingmodeDropDown.Value;
           app.ana.calStim = app.CalibrationstimulusDropDown.Value;
           app.ana.sampleRate = str2num(app.SamplerateKnob.Value);
           
           app.ana.calFile = app.CalibrationEditField.Value;
           app.ana.calPos = str2num(app.CalPositionsEditField.Value);
           app.ana.valPos = str2num(app.ValPositionsEditField.Value);
           app.ana.autoPace = logical(app.AutoPaceCheckBox.Value);
           app.ana.paceDuration = app.PaceDurationEditField.Value;
           
           
           app.ana.initTime = app.InitTimeEditField.Value;
           app.ana.fixTime = app.FixTimeEditField.Value;
           app.ana.radius = app.RadiusEditField.Value;
           app.ana.strict = logical(app.StrictEditField.Value);
           app.ana.spotSize = app.SpotSizeEditField.Value;
           app.ana.drawEye = logical(app.DrawEyePositionCheckBox.Value);
           
           app.ana.debug = app.DebugModeCheckBox.Value;
           
           app.ana.plotAxis1 = app.PlotAxis1;
           app.ana.plotAxis2 = app.PlotAxis2;
            
        end
        
        function p = setPath(app)
            s=metaStimulus();
            app.SavePathEditField.Value=s.paths.savedData;
            app.ImagesEditField.Value=[s.paths.parent filesep 'Pictures'];
            p = app.SavePathEditField.Value;
            clear s;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           p=app.setPath();
           cd(p);
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
           p=app.setPath();
           cd(p);
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runBasicTraining(app.ana)
        end

        % Button pushed function: SP2Button
        function SP2ButtonPushed(app, event)
            p = uigetdir(app.ImagesEditField.Value);
            app.ImagesEditField.Value = p;
        end

        % Button pushed function: CalButton
        function CalButtonPushed(app, event)
            [f,p]=uigetfile('*.mat');
            if isnumeric(f);return;end   
            app.CalibrationEditField.Value = [p f];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasicTrainingAppUIFigure = uifigure('Visible', 'off');
            app.BasicTrainingAppUIFigure.Name = 'Basic Training App';
            app.TabGroup = uitabgroup(app.BasicTrainingAppUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.OptionsPanel = uipanel(app.SettingsTab);
            app.OptionsPanel.Title = 'Options';
            app.StimulusSizedegEditFieldLabel = uilabel(app.OptionsPanel);
            app.StimulusSizedegEditFieldLabel.Text = 'Stimulus Size (deg)';
            app.StimulusSizedegEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.StimulusSizedegEditField.Tooltip = {'0 means use the native size of the image/movie'};
            app.RewardatstartCheckBox = uicheckbox(app.OptionsPanel);
            app.RewardatstartCheckBox.Text = 'Reward at start?';
            app.RewardduringCheckBox = uicheckbox(app.OptionsPanel);
            app.RewardduringCheckBox.Text = 'Reward during?';
            app.RewardatendCheckBox = uicheckbox(app.OptionsPanel);
            app.RewardatendCheckBox.Text = 'Reward at end?';
            app.RewardatendCheckBox.Value = true;
            app.PlaytimesEditFieldLabel = uilabel(app.OptionsPanel);
            app.PlaytimesEditFieldLabel.Text = 'Play time (s)';
            app.PlaytimesEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.PlaytimesEditField.Value = 1;
            app.HTML = uihtml(app.OptionsPanel);
            app.HTML.HTMLSource = '<div style="text-align:justify;font-size:small;font-family:liberation sans,avenir,sans-serif;"><p >You can use automatic rewards or manual ones. Press:<br/><br/><b>1</b> Give reward.<br/><b>2</b> Give positive feedback + reward. <br/><b>3</b> Give <i>negative</i> feedback.<br/><b>p</b> Pause.<br/><b>q</b> Quit.</p></div>';
            app.SPButton = uibutton(app.OptionsPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Icon = 'go-to-file.png';
            app.SPButton.Text = '';
            app.InfoEditFieldLabel = uilabel(app.OptionsPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.OptionsPanel, 'text');
            app.SubjectEditFieldLabel = uilabel(app.OptionsPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.OptionsPanel, 'text');
            app.SubjectEditField.Value = 'Joanna Doe';
            app.SavePathEditFieldLabel = uilabel(app.OptionsPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.OptionsPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/IsoLum/';
            app.GOButton = uibutton(app.OptionsPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Icon = 'start.png';
            app.GOButton.Text = 'GO!';
            app.DistanceEditFieldLabel = uilabel(app.OptionsPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DistanceEditField.Value = 60;
            app.PixelsPerCmEditFieldLabel = uilabel(app.OptionsPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.PixelsPerCmEditField.Value = 32;
            app.StimulusDropDownLabel = uilabel(app.OptionsPanel);
            app.StimulusDropDownLabel.Text = 'Stimulus';
            app.StimulusDropDown = uidropdown(app.OptionsPanel);
            app.StimulusDropDown.Items = {'Dancing Monkey', 'Pictures', 'Fixation Spot'};
            app.StimulusDropDown.Value = 'Dancing Monkey';
            app.SP2Button = uibutton(app.OptionsPanel, 'push');
            app.SP2Button.ButtonPushedFcn = createCallbackFcn(app, @SP2ButtonPushed, true);
            app.SP2Button.Icon = 'go-to-file.png';
            app.SP2Button.Text = '';
            app.ImagesEditFieldLabel = uilabel(app.OptionsPanel);
            app.ImagesEditFieldLabel.Text = 'Images';
            app.ImagesEditField = uieditfield(app.OptionsPanel, 'text');
            app.ImagesEditField.Value = '/home/cog5/Documents/Monkey-Pictures';
            app.RandomPositionCheckBox = uicheckbox(app.OptionsPanel);
            app.RandomPositionCheckBox.Text = 'Random Position';
            app.RandomPositionCheckBox.Value = true;
            app.PositionsEditFieldLabel = uilabel(app.OptionsPanel);
            app.PositionsEditFieldLabel.Text = 'Positions';
            app.PositionsEditField = uieditfield(app.OptionsPanel, 'text');
            app.PositionsEditField.Value = '[ -12 -10; -12 0; 0 -10; 0 0; 12 0; 0 10; 12 10 ]';
            app.ITIsEditFieldLabel = uilabel(app.OptionsPanel);
            app.ITIsEditFieldLabel.Tooltip = {'Inter trial interval'};
            app.ITIsEditFieldLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ITIsEditField.Value = 1;
            app.TimeOutsEditFieldLabel = uilabel(app.OptionsPanel);
            app.TimeOutsEditFieldLabel.Text = 'Time Out (s)';
            app.TimeOutsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TimeOutsEditField.Tooltip = {'Time out when incorrect'};
            app.TimeOutsEditField.Value = 4;
            app.DebugModeCheckBox = uicheckbox(app.OptionsPanel);
            app.DebugModeCheckBox.Text = 'Debug Mode?';
            app.EyetrackersettingsPanel = uipanel(app.SettingsTab);
            app.EyetrackersettingsPanel.Title = 'Eyetracker settings';
            app.StrictEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.StrictEditFieldLabel.Text = 'Strict';
            app.StrictEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.StrictEditField.Limits = [0 1];
            app.StrictEditField.RoundFractionalValues = 'on';
            app.StrictEditField.ValueDisplayFormat = '%.0f';
            app.StrictEditField.Tooltip = {'0 means use the native size of the image/movie'};
            app.StrictEditField.Value = 1;
            app.RadiusEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.RadiusEditField.Limits = [0.1 100];
            app.RadiusEditField.Value = 10;
            app.InitTimeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.InitTimeEditFieldLabel.Text = 'Init Time';
            app.InitTimeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.InitTimeEditField.Limits = [0 100];
            app.InitTimeEditField.Value = 3;
            app.FixTimeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.FixTimeEditFieldLabel.Text = {'Fix Time'; ''};
            app.FixTimeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.FixTimeEditField.Limits = [0 100];
            app.FixTimeEditField.Value = 0.6;
            app.TrackerDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.TrackerDropDownLabel.Text = 'Tracker';
            app.TrackerDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.TrackerDropDown.Items = {'Tobii Pro Spectrum', 'Tobii TX300'};
            app.TrackerDropDown.Value = 'Tobii TX300';
            app.TrackingmodeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.TrackingmodeDropDownLabel.Text = 'Tracking mode';
            app.TrackingmodeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.TrackingmodeDropDown.Items = {'human', 'macaque', 'Default', 'Infant', 'Bright light'};
            app.TrackingmodeDropDown.Value = 'Default';
            app.CalPositionsEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalPositionsEditFieldLabel.Text = 'Cal Positions';
            app.CalPositionsEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.CalPositionsEditField.Value = '[ 0.2 0.5; 0.5 0.5; 0.8 0.5 ]';
            app.CalibrationstimulusDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalibrationstimulusDropDownLabel.Text = 'Calibration stimulus';
            app.CalibrationstimulusDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.CalibrationstimulusDropDown.Items = {'animated', 'movie', 'normal'};
            app.CalibrationstimulusDropDown.Value = 'animated';
            app.ValPositionsEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.ValPositionsEditFieldLabel.Text = 'Val Positions';
            app.ValPositionsEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.ValPositionsEditField.Value = '[ 0.5 0.5 ]';
            app.AutoPaceCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.AutoPaceCheckBox.Text = 'Auto Pace';
            app.SamplerateKnobLabel = uilabel(app.EyetrackersettingsPanel);
            app.SamplerateKnobLabel.Text = 'Sample rate';
            app.SamplerateKnob = uiknob(app.EyetrackersettingsPanel, 'discrete');
            app.SamplerateKnob.Items = {'60', '90', '120', '150', '300', '600', '1200'};
            app.SamplerateKnob.Value = '300';
            app.UseEyeTrackerCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.UseEyeTrackerCheckBox.Text = 'Use Eye Tracker?';
            app.DummyModeCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.DummyModeCheckBox.Text = 'Dummy Mode?';
            app.DrawEyePositionCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.DrawEyePositionCheckBox.Text = 'Draw Eye Position?';
            app.CalButton = uibutton(app.EyetrackersettingsPanel, 'push');
            app.CalButton.ButtonPushedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.CalButton.Icon = 'go-to-file.png';
            app.CalButton.Text = '';
            app.CalibrationEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.PaceDurationEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.PaceDurationEditField.Tooltip = {'Pace duration (s)'};
            app.PaceDurationEditField.Value = 0.8;
            app.SpotSizeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotSizeEditFieldLabel.Text = 'Spot Size';
            app.SpotSizeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotSizeEditField.Limits = [0 100];
            app.SpotSizeEditField.Value = 1;
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.ResultsPanel = uipanel(app.PlotsTab);
            app.ResultsPanel.Title = 'Results';
            app.PlotAxis1 = uiaxes(app.ResultsPanel);
            title(app.PlotAxis1, 'Rewards')
            xlabel(app.PlotAxis1, '')
            ylabel(app.PlotAxis1, '#')
            app.PlotAxis2 = uiaxes(app.ResultsPanel);
            title(app.PlotAxis2, 'Responses')
            xlabel(app.PlotAxis2, '')
            ylabel(app.PlotAxis2, '#')
            % Show the figure after all components are created
            app.BasicTrainingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasicTrainingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasicTrainingAppUIFigure)
        end
    end
end