classdef behaviouralAcuity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskedAfterImageTestsUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        GeneralPanel                   matlab.ui.container.Panel
        CButton                        matlab.ui.control.Button
        SPButton                       matlab.ui.control.Button
        InfoEditFieldLabel             matlab.ui.control.Label
        InfoEditField                  matlab.ui.control.EditField
        SubjectEditFieldLabel          matlab.ui.control.Label
        SubjectEditField               matlab.ui.control.EditField
        SavePathEditFieldLabel         matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        CalibrationEditFieldLabel      matlab.ui.control.Label
        CalibrationEditField           matlab.ui.control.EditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel      matlab.ui.control.Label
        PixelsPerCmEditField           matlab.ui.control.NumericEditField
        DebugModeSwitchLabel           matlab.ui.control.Label
        DebugModeSwitch                matlab.ui.control.RockerSwitch
        BackgroundColorEditFieldLabel  matlab.ui.control.Label
        BackgroundColorEditField       matlab.ui.control.EditField
        TaskListBoxLabel               matlab.ui.control.Label
        TaskListBox                    matlab.ui.control.ListBox
        nBlocksEditFieldLabel          matlab.ui.control.Label
        nBlocksEditField               matlab.ui.control.NumericEditField
        AdditionalOptionsPanel         matlab.ui.container.Panel
        StairCaseSwitchLabel           matlab.ui.control.Label
        StairCaseSwitch                matlab.ui.control.Switch
        MaxEditFieldLabel              matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        StimulusSizeEditFieldLabel     matlab.ui.control.Label
        StimulusSizeEditField          matlab.ui.control.NumericEditField
        ChoosePosEditFieldLabel        matlab.ui.control.Label
        ChoosePosEditField             matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        PFDropDownLabel                matlab.ui.control.Label
        PFDropDown                     matlab.ui.control.DropDown
        gammaEditFieldLabel            matlab.ui.control.Label
        gammaEditField                 matlab.ui.control.NumericEditField
        betamaxEditFieldLabel          matlab.ui.control.Label
        betamaxEditField               matlab.ui.control.NumericEditField
        lambdaEditFieldLabel           matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.NumericEditField
        ConstrastRangeEditFieldLabel   matlab.ui.control.Label
        ConstrastRangeEditField        matlab.ui.control.EditField
        PriorsSwitchLabel              matlab.ui.control.Label
        PriorsSwitch                   matlab.ui.control.Switch
        LogalphaCheckBox               matlab.ui.control.CheckBox
        alphaSDEditFieldLabel          matlab.ui.control.Label
        alphaSDEditField               matlab.ui.control.NumericEditField
        alphapriorWEditFieldLabel      matlab.ui.control.Label
        alphapriorWEditField           matlab.ui.control.NumericEditField
        betaSDEditFieldLabel           matlab.ui.control.Label
        betaSDEditField                matlab.ui.control.NumericEditField
        betapriorEditFieldLabel        matlab.ui.control.Label
        betapriorEditField             matlab.ui.control.NumericEditField
        marginaliseEditFieldLabel      matlab.ui.control.Label
        marginaliseEditField           matlab.ui.control.EditField
        TestButton                     matlab.ui.control.Button
        LogbetaCheckBox                matlab.ui.control.CheckBox
        betagrainEditFieldLabel        matlab.ui.control.Label
        betagrainEditField             matlab.ui.control.NumericEditField
        alphagrainEditFieldLabel       matlab.ui.control.Label
        alphagrainEditField            matlab.ui.control.NumericEditField
        alphapriorBEditFieldLabel      matlab.ui.control.Label
        alphapriorBEditField           matlab.ui.control.NumericEditField
        DiskColorEditFieldLabel        matlab.ui.control.Label
        DiskColorEditField             matlab.ui.control.EditField
        RunPanel                       matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        GOButton                       matlab.ui.control.Button
        EyeTrackerTab                  matlab.ui.container.Tab
        EyetrackersettingsPanel        matlab.ui.container.Panel
        StrictEditFieldLabel           matlab.ui.control.Label
        StrictEditField                matlab.ui.control.NumericEditField
        INITEditFieldLabel             matlab.ui.control.Label
        INITEditField                  matlab.ui.control.NumericEditField
        FIXEditFieldLabel              matlab.ui.control.Label
        FIXEditField                   matlab.ui.control.NumericEditField
        TrackerDropDownLabel           matlab.ui.control.Label
        TrackerDropDown                matlab.ui.control.DropDown
        TrackingmodeDropDownLabel      matlab.ui.control.Label
        TrackingmodeDropDown           matlab.ui.control.DropDown
        CalibrationstimulusDropDownLabel  matlab.ui.control.Label
        CalibrationstimulusDropDown    matlab.ui.control.DropDown
        EyeusedDropDownLabel           matlab.ui.control.Label
        EyeusedDropDown                matlab.ui.control.DropDown
        CalEditFieldLabel              matlab.ui.control.Label
        CalEditField                   matlab.ui.control.EditField
        ValEditFieldLabel              matlab.ui.control.Label
        ValEditField                   matlab.ui.control.EditField
        AutoPaceCheckBox               matlab.ui.control.CheckBox
        eyeCALButton                   matlab.ui.control.Button
        CalibrationEditField_2Label    matlab.ui.control.Label
        CalibrationEditField_2         matlab.ui.control.EditField
        PaceDurationEditField          matlab.ui.control.NumericEditField
        ReloadPreviousCheckBox         matlab.ui.control.CheckBox
        ManualCheckBox                 matlab.ui.control.CheckBox
        UseEyeTrackerCheckBox          matlab.ui.control.CheckBox
        DummyModeCheckBox              matlab.ui.control.CheckBox
        SpotSizeEditFieldLabel         matlab.ui.control.Label
        SpotSizeEditField              matlab.ui.control.NumericEditField
        SamplerateKnobLabel            matlab.ui.control.Label
        SamplerateKnob                 matlab.ui.control.DiscreteKnob
        EyeDropDownLabel               matlab.ui.control.Label
        smoothEyeDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        smoothMethodDropDown           matlab.ui.control.DropDown
        wEditFieldLabel                matlab.ui.control.Label
        windowEditField                matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        nSamplesEditField              matlab.ui.control.NumericEditField
        msLabel                        matlab.ui.control.Label
        SpotAlphaEditFieldLabel        matlab.ui.control.Label
        SpotAlphaEditField             matlab.ui.control.NumericEditField
        SpotLineEditFieldLabel         matlab.ui.control.Label
        SpotLineEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        GraceTimeEditFieldLabel        matlab.ui.control.Label
        GraceTimeEditField             matlab.ui.control.NumericEditField
        RADIUSEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.EditField
        DrawEyeDropDownLabel           matlab.ui.control.Label
        DrawEyeDropDown                matlab.ui.control.DropDown
        PlotsTab                       matlab.ui.container.Tab
        Data1Axes                      matlab.ui.control.UIAxes
        Data2Axes                      matlab.ui.control.UIAxes
        Data4Axes                      matlab.ui.control.UIAxes
        Data3Axes                      matlab.ui.control.UIAxes
    end


	properties (Access = private)
		ana % main settings
	end

	methods (Access = private)

		function parseSettings(app)
			app.ana = struct();
			app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
			app.ana.subject = app.SubjectEditField.Value;
			app.ana.comments = app.InfoEditField.Value;
			app.ana.ResultDir = app.SavePathEditField.Value;
			app.ana.gammaTable = app.CalibrationEditField.Value;
			app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
			app.ana.distance = app.DistanceEditField.Value;
			app.ana.nBlocks = app.nBlocksEditField.Value;
			app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);

			% Stimulus options
			app.ana.maxTime = app.MaxEditField.Value;
			app.ana.minTime = app.MinEditField.Value;
			app.ana.maskTime = app.ChoosePosEditField.Value;
			app.ana.discSize = app.StimulusSizeEditField.Value;
			app.ana.sigma = app.SigmaEditField.Value;
			app.ana.contrastRange = str2num(app.ConstrastRangeEditField.Value);
			
			app.ana.betaMax = app.betamaxEditField.Value;
			app.ana.gamma = app.gammaEditField.Value;
			app.ana.lambda = app.lambdaEditField.Value;
			val = str2num(app.marginaliseEditField.Value);
			if isempty(val)
				app.ana.marginalize = [];
			else
				app.ana.marginalize = val;
			end
			app.ana.alphaPriorW = app.alphapriorWEditField.Value;
			app.ana.alphaPriorB = app.alphapriorBEditField.Value;
			app.ana.alphaPrior = app.ana.alphaPriorW;
			app.ana.alphaSD = app.alphaSDEditField.Value;
			app.ana.betaPrior = app.betapriorEditField.Value;
			app.ana.betaSD = app.betaSDEditField.Value;
			app.ana.alphaGrain = app.alphagrainEditField.Value;
			app.ana.betaGrain = app.betagrainEditField.Value;

			% EYETRACKER
			app.ana.useTracker = logical(app.UseEyeTrackerCheckBox.Value);
			app.ana.isDummy = logical(app.DummyModeCheckBox.Value);
			app.ana.tracker = app.TrackerDropDown.Value;
			app.ana.trackingMode = app.TrackingmodeDropDown.Value;
			app.ana.eyeUsed = app.EyeusedDropDown.Value;
			app.ana.calStim = app.CalibrationstimulusDropDown.Value;
			app.ana.sampleRate = str2num(app.SamplerateKnob.Value);

			app.ana.calFile = app.CalibrationEditField.Value;
			app.ana.calPos = str2num(app.CalEditField.Value);
			app.ana.valPos = str2num(app.ValEditField.Value);
			app.ana.calManual = logical(app.ManualCheckBox.Value);
			app.ana.reloadPreviousCal = logical(app.ReloadPreviousCheckBox.Value);
			app.ana.autoPace = logical(app.AutoPaceCheckBox.Value);
			app.ana.paceDuration = app.PaceDurationEditField.Value;

			app.ana.initTime = app.INITEditField.Value;
			app.ana.fixTime = app.FIXEditField.Value;
			app.ana.radius = str2num(app.RadiusEditField.Value);
			app.ana.XFix = app.XEditField.Value;
			app.ana.YFix = app.YEditField.Value;
			app.ana.strict = logical(app.StrictEditField.Value);
			app.ana.graceTime = app.GraceTimeEditField.Value;
			app.ana.spotSize = app.SpotSizeEditField.Value;
			app.ana.spotAlpha = app.SpotAlphaEditField.Value;
			app.ana.spotLine = app.SpotLineEditField.Value;
			app.ana.nSamples = app.nSamplesEditField.Value;
			app.ana.w = app.windowEditField.Value;
			app.ana.smoothEye =  app.smoothEyeDropDown.Value;
			app.ana.smoothMethod = app.smoothMethodDropDown.Value;
			app.ana.drawEye = app.DrawEyeDropDown.Value;

			app.ana.PF = eval(['@PAL_' app.PFDropDown.Value]);
			app.ana.logTransform = logical(app.LogalphaCheckBox.Value);
			app.ana.logSlope = logical(app.LogbetaCheckBox.Value);

			app.ana.myFunc = [app.TaskListBox.Value '(app.ana)'];

			if strcmpi(app.StairCaseSwitch.Value,'On')
				app.ana.useStaircase = true;
			else
				app.ana.useStaircase = false;
			end
			if strcmpi(app.PriorsSwitch.Value,'On')
				app.ana.usePriors = true;
			else
				app.ana.usePriors = false;
			end

			if strcmpi(app.DebugModeSwitch.Value,'On')
				app.ana.debug = true;
				app.ana.windowed = [0 0 1000 1000];
			else
				app.ana.debug = false;
				app.ana.windowed = [];
			end

			app.ana.plotAxis1 = app.Data1Axes;
			app.ana.plotAxis2 = app.Data2Axes;
			app.ana.plotAxis3 = app.Data3Axes;
			app.ana.plotAxis4 = app.Data4Axes;
		end

	end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
			app.SavePathEditField.Value = [getenv('HOME') '/Desktop/'];
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
			event.Source.BusyAction = 'cancel';
			event.Source.Interruptible = 'off';
			app.parseSettings()
			fprintf('\n=========RUN: %s============\n',app.ana.myFunc)
			runBehaviouralAcuity(app.ana);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
			app.parseSettings();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
			delete(app);
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
			[f, p] = uigetfile('*.mat','Select a Calibration File');
			app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
			app.SavePathEditField.Value = uigetdir;
        end

        % Value changed function: TaskListBox
        function TaskListBoxValueChanged(app, event)
			value = app.TaskListBox.Value;
			switch value
				case 'AIContrast'

				case 'ConThreshold'

				case 'AIConThreshold'

			end

        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
			range = app.ConstrastRangeEditField.Value;
			plotPF(range);
        end

        % Value changed function: TrackerDropDown
        function TrackerDropDownValueChanged(app, event)
			value = app.TrackerDropDown.Value;
            switch value
                case 'Tobii TX300'
                    app.TrackingmodeDropDown.Enable = true;
                    app.EyeusedDropDown.Enable = true;
                    app.TrackingmodeDropDown.Items = {'Default','Infant','Bright light'};
                    app.TrackingmodeDropDown.Value = 'Default';
                    app.SamplerateKnob.Items = {'60', '120','300'};
                    app.SamplerateKnob.Value = '300';
                    app.SamplerateKnob.Enable = true;
                case 'Tobii Pro Spectrum'
                    app.TrackingmodeDropDown.Enable = true;
                    app.EyeusedDropDown.Enable = true;
                    app.TrackingmodeDropDown.Items = {'human','macaque'};
                    app.TrackingmodeDropDown.Value = 'human';
                    app.SamplerateKnob.Items = {'60', '90','120','150','300','600','1200'};
                    app.SamplerateKnob.Value = '300';
                    app.SamplerateKnob.Enable = true;
                case 'Tobii 4C'
                    app.EyeusedDropDown.Enable = false;
                    app.TrackingmodeDropDown.Enable = false;
                    app.SamplerateKnob.Items = {'60','90'};
                    app.SamplerateKnob.Value = '90';
                    app.SamplerateKnob.Enable = false;
            end
        end

        % Value changed function: SamplerateKnob
        function SamplerateKnobValueChanged(app, event)
			value = app.SamplerateKnob.Value;
            n = app.nSamplesEditField.Value;
            f = str2num(app.SamplerateKnob.Value);
            app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
        end

        % Value changed function: nSamplesEditField
        function nSamplesEditFieldValueChanged(app, event)
			value = app.nSamplesEditField.Value;
            n = app.nSamplesEditField.Value;
            f = str2num(app.SamplerateKnob.Value);
            app.msLabel.Text = sprintf('%.1fms',((1/f)*n)*1e3);
        end

        % Button pushed function: eyeCALButton
        function eyeCALButtonPushed(app, event)
			[f,p]=uigetfile('*.mat','Please load Tobii Calibration MAT file');
            if isnumeric(f);return;end   
            app.CalibrationEditField.Value = [p f];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskedAfterImageTestsUIFigure = uifigure('Visible', 'off');
            app.MaskedAfterImageTestsUIFigure.Name = 'Masked AfterImage Tests';
            app.TabGroup = uitabgroup(app.MaskedAfterImageTestsUIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GeneralPanel = uipanel(app.ControlTab);
            app.GeneralPanel.Title = 'General';
            app.CButton = uibutton(app.GeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Icon = 'go-to-file.png';
            app.CButton.Text = '';
            app.SPButton = uibutton(app.GeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Icon = 'go-to-file.png';
            app.SPButton.Text = '';
            app.InfoEditFieldLabel = uilabel(app.GeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.GeneralPanel, 'text');
            app.InfoEditField.Value = 'Behavioural Acuity Test';
            app.SubjectEditFieldLabel = uilabel(app.GeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GeneralPanel, 'text');
            app.SubjectEditField.Value = 'DEBUG';
            app.SavePathEditFieldLabel = uilabel(app.GeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.GeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/';
            app.CalibrationEditFieldLabel = uilabel(app.GeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.GeneralPanel, 'text');
            app.DistanceEditFieldLabel = uilabel(app.GeneralPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.DistanceEditField.Limits = [20 200];
            app.DistanceEditField.Value = 62.5;
            app.PixelsPerCmEditFieldLabel = uilabel(app.GeneralPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.PixelsPerCmEditField.Limits = [5 300];
            app.PixelsPerCmEditField.Value = 27;
            app.DebugModeSwitchLabel = uilabel(app.GeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.BackgroundColorEditFieldLabel = uilabel(app.GeneralPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.GeneralPanel, 'text');
            app.BackgroundColorEditField.Value = '0.25 0.25 0.25';
            app.TaskListBoxLabel = uilabel(app.GeneralPanel);
            app.TaskListBoxLabel.Text = 'Task';
            app.TaskListBox = uilistbox(app.GeneralPanel);
            app.TaskListBox.Items = {'GratingChangeTask'};
            app.TaskListBox.ValueChangedFcn = createCallbackFcn(app, @TaskListBoxValueChanged, true);
            app.TaskListBox.Value = 'GratingChangeTask';
            app.nBlocksEditFieldLabel = uilabel(app.GeneralPanel);
            app.nBlocksEditFieldLabel.Text = 'nBlocks';
            app.nBlocksEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.nBlocksEditField.Limits = [1 2000];
            app.nBlocksEditField.Value = 5;
            app.AdditionalOptionsPanel = uipanel(app.ControlTab);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.StairCaseSwitchLabel = uilabel(app.AdditionalOptionsPanel);
            app.StairCaseSwitchLabel.Text = 'StairCase?';
            app.StairCaseSwitch = uiswitch(app.AdditionalOptionsPanel, 'slider');
            app.MaxEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.MaxEditField.Limits = [0 300];
            app.MaxEditField.Value = 1;
            app.MinEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.MinEditField.Limits = [0 200];
            app.MinEditField.Value = 1;
            app.StimulusSizeEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.StimulusSizeEditFieldLabel.Text = 'Stimulus Size';
            app.StimulusSizeEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.StimulusSizeEditField.Limits = [0 300];
            app.StimulusSizeEditField.Value = 10;
            app.ChoosePosEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.ChoosePosEditFieldLabel.Text = 'ChoosePos';
            app.ChoosePosEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.ChoosePosEditField.Limits = [-100 100];
            app.ChoosePosEditField.Value = -8;
            app.SigmaEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.SigmaEditField.Limits = [0 300];
            app.PFDropDownLabel = uilabel(app.AdditionalOptionsPanel);
            app.PFDropDownLabel.Text = 'PF';
            app.PFDropDown = uidropdown(app.AdditionalOptionsPanel);
            app.PFDropDown.Items = {'Weibull', 'Quick', 'Gumbel', 'logQuick', 'Logistic', 'CumulativeNormal', 'HyperbolicSecant'};
            app.PFDropDown.Editable = 'on';
            app.PFDropDown.Value = 'Quick';
            app.gammaEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.gammaEditField.Limits = [0 300];
            app.betamaxEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betamaxEditFieldLabel.Text = 'beta max';
            app.betamaxEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betamaxEditField.Limits = [0 2000];
            app.betamaxEditField.Value = 30;
            app.lambdaEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.lambdaEditField.Limits = [0 200];
            app.lambdaEditField.Value = 0.01;
            app.ConstrastRangeEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.ConstrastRangeEditFieldLabel.Text = 'Constrast Range';
            app.ConstrastRangeEditField = uieditfield(app.AdditionalOptionsPanel, 'text');
            app.ConstrastRangeEditField.Value = '0.1 0.2 0.3';
            app.PriorsSwitchLabel = uilabel(app.AdditionalOptionsPanel);
            app.PriorsSwitchLabel.Text = 'Priors?';
            app.PriorsSwitch = uiswitch(app.AdditionalOptionsPanel, 'slider');
            app.PriorsSwitch.Value = 'On';
            app.LogalphaCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.LogalphaCheckBox.Text = 'Log alpha';
            app.alphaSDEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphaSDEditFieldLabel.Text = 'alpha SD';
            app.alphaSDEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphaSDEditField.Limits = [0 300];
            app.alphaSDEditField.Value = 0.2;
            app.alphapriorWEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphapriorWEditFieldLabel.Text = 'alpha priorW';
            app.alphapriorWEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphapriorWEditField.Limits = [0 2000];
            app.alphapriorWEditField.Value = 0.4;
            app.betaSDEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betaSDEditFieldLabel.Text = 'beta SD';
            app.betaSDEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betaSDEditField.Limits = [0 300];
            app.betaSDEditField.Value = 5;
            app.betapriorEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betapriorEditFieldLabel.Text = 'beta prior';
            app.betapriorEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betapriorEditField.Limits = [0 200];
            app.betapriorEditField.Value = 15;
            app.marginaliseEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.marginaliseEditFieldLabel.Text = 'marginalise';
            app.marginaliseEditField = uieditfield(app.AdditionalOptionsPanel, 'text');
            app.marginaliseEditField.Value = '''slope''';
            app.TestButton = uibutton(app.AdditionalOptionsPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.LogbetaCheckBox = uicheckbox(app.AdditionalOptionsPanel);
            app.LogbetaCheckBox.Text = 'Log beta';
            app.LogbetaCheckBox.Value = true;
            app.betagrainEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.betagrainEditFieldLabel.Text = 'beta grain';
            app.betagrainEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.betagrainEditField.Limits = [0 1000];
            app.betagrainEditField.Value = 50;
            app.alphagrainEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphagrainEditFieldLabel.Text = 'alpha grain';
            app.alphagrainEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphagrainEditField.Limits = [0 1000];
            app.alphagrainEditField.Value = 200;
            app.alphapriorBEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.alphapriorBEditFieldLabel.Text = 'alpha priorB';
            app.alphapriorBEditField = uieditfield(app.AdditionalOptionsPanel, 'numeric');
            app.alphapriorBEditField.Limits = [0 2000];
            app.alphapriorBEditField.Value = 0.4;
            app.DiskColorEditFieldLabel = uilabel(app.AdditionalOptionsPanel);
            app.DiskColorEditFieldLabel.Text = 'Disk Color';
            app.DiskColorEditField = uieditfield(app.AdditionalOptionsPanel, 'text');
            app.DiskColorEditField.Value = '0.5 0.5 0.5';
            app.RunPanel = uipanel(app.ControlTab);
            app.RunPanel.Title = 'Run';
            app.SaveButton = uibutton(app.RunPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.RunPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'stop.png';
            app.ExitButton.Text = 'Exit!';
            app.GOButton = uibutton(app.RunPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Icon = 'start.png';
            app.GOButton.Text = 'GO!';
            app.EyeTrackerTab = uitab(app.TabGroup);
            app.EyeTrackerTab.Title = 'EyeTracker';
            app.EyetrackersettingsPanel = uipanel(app.EyeTrackerTab);
            app.EyetrackersettingsPanel.Title = 'Eyetracker settings';
            app.StrictEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.StrictEditFieldLabel.Text = 'Strict';
            app.StrictEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.StrictEditField.Limits = [0 1];
            app.StrictEditField.RoundFractionalValues = 'on';
            app.StrictEditField.ValueDisplayFormat = '%.0f';
            app.StrictEditField.Tooltip = {'0 means use the native size of the image/movie'};
            app.INITEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.INITEditFieldLabel.Text = 'INIT';
            app.INITEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.INITEditField.Limits = [0 100];
            app.INITEditField.Tooltip = {'Time to initiate fixation'};
            app.INITEditField.Value = 3;
            app.FIXEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.FIXEditFieldLabel.Text = 'FIX';
            app.FIXEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.FIXEditField.Limits = [0 100];
            app.FIXEditField.Tooltip = {'Minimum time to stay fixated'};
            app.FIXEditField.Value = 0.6;
            app.TrackerDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.TrackerDropDownLabel.Text = 'Tracker';
            app.TrackerDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.TrackerDropDown.Items = {'Tobii Pro Spectrum', 'Tobii TX300', 'Tobii 4C'};
            app.TrackerDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackerDropDownValueChanged, true);
            app.TrackerDropDown.Value = 'Tobii Pro Spectrum';
            app.TrackingmodeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.TrackingmodeDropDownLabel.Text = 'Tracking mode';
            app.TrackingmodeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.TrackingmodeDropDown.Items = {'human', 'macaque'};
            app.TrackingmodeDropDown.Value = 'human';
            app.CalibrationstimulusDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalibrationstimulusDropDownLabel.Text = 'Calibration stimulus';
            app.CalibrationstimulusDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.CalibrationstimulusDropDown.Items = {'animated', 'movie', 'normal'};
            app.CalibrationstimulusDropDown.Value = 'animated';
            app.EyeusedDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.EyeusedDropDownLabel.Text = 'Eye used';
            app.EyeusedDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.EyeusedDropDown.Items = {'both', 'left', 'right'};
            app.EyeusedDropDown.Value = 'both';
            app.CalEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.CalEditFieldLabel.Text = 'Cal';
            app.CalEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.CalEditField.Value = '[ 0.2 0.5; 0.5 0.5; 0.8 0.5 ]';
            app.ValEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.ValEditFieldLabel.Text = 'Val';
            app.ValEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.ValEditField.Value = '[ 0.5 0.5 ]';
            app.AutoPaceCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.AutoPaceCheckBox.Text = 'Auto Pace';
            app.eyeCALButton = uibutton(app.EyetrackersettingsPanel, 'push');
            app.eyeCALButton.ButtonPushedFcn = createCallbackFcn(app, @eyeCALButtonPushed, true);
            app.eyeCALButton.Icon = 'go-to-file.png';
            app.eyeCALButton.Text = '';
            app.CalibrationEditField_2Label = uilabel(app.EyetrackersettingsPanel);
            app.CalibrationEditField_2Label.Text = 'Calibration';
            app.CalibrationEditField_2 = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.PaceDurationEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.PaceDurationEditField.Tooltip = {'Pace duration (s)'};
            app.PaceDurationEditField.Value = 0.8;
            app.ReloadPreviousCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.ReloadPreviousCheckBox.Tooltip = {'Reload previous calibration file automatically?'};
            app.ReloadPreviousCheckBox.Text = 'Reload Previous?';
            app.ManualCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.ManualCheckBox.Text = 'Manual';
            app.UseEyeTrackerCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.UseEyeTrackerCheckBox.Enable = 'off';
            app.UseEyeTrackerCheckBox.Text = 'Use Eye Tracker?';
            app.UseEyeTrackerCheckBox.Value = true;
            app.DummyModeCheckBox = uicheckbox(app.EyetrackersettingsPanel);
            app.DummyModeCheckBox.Text = 'Dummy Mode?';
            app.SpotSizeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotSizeEditFieldLabel.Text = 'Spot Size';
            app.SpotSizeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotSizeEditField.Limits = [0 100];
            app.SpotSizeEditField.Value = 1.25;
            app.SamplerateKnobLabel = uilabel(app.EyetrackersettingsPanel);
            app.SamplerateKnobLabel.Text = 'Sample rate';
            app.SamplerateKnob = uiknob(app.EyetrackersettingsPanel, 'discrete');
            app.SamplerateKnob.Items = {'60', '90', '120', '150', '300', '600', '1200'};
            app.SamplerateKnob.ValueChangedFcn = createCallbackFcn(app, @SamplerateKnobValueChanged, true);
            app.SamplerateKnob.Value = '300';
            app.EyeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.EyeDropDownLabel.Text = {'Eye'; ''};
            app.smoothEyeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.smoothEyeDropDown.Items = {'both', 'left', 'right'};
            app.smoothEyeDropDown.Tooltip = {'smoothing use eye data?'};
            app.smoothEyeDropDown.Value = 'both';
            app.MethodDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.smoothMethodDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.smoothMethodDropDown.Items = {'median', 'heuristic1', 'heuristic2', 'savitsky-golay', 'basic'};
            app.smoothMethodDropDown.Tooltip = {'smoothing method'};
            app.smoothMethodDropDown.Value = 'median';
            app.wEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.wEditFieldLabel.Text = 'w';
            app.windowEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.windowEditField.Limits = [1 100];
            app.windowEditField.RoundFractionalValues = 'on';
            app.windowEditField.ValueDisplayFormat = '%.0f';
            app.windowEditField.Tooltip = {'smoothing window'};
            app.windowEditField.Value = 3;
            app.EditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.EditFieldLabel.Text = '#';
            app.nSamplesEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.nSamplesEditField.Limits = [1 200];
            app.nSamplesEditField.RoundFractionalValues = 'on';
            app.nSamplesEditField.ValueDisplayFormat = '%.0f';
            app.nSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @nSamplesEditFieldValueChanged, true);
            app.nSamplesEditField.Tooltip = {'# of samples to smooth'};
            app.nSamplesEditField.Value = 4;
            app.msLabel = uilabel(app.EyetrackersettingsPanel);
            app.msLabel.Text = '20ms';
            app.SpotAlphaEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotAlphaEditFieldLabel.Text = 'Spot Alpha';
            app.SpotAlphaEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotAlphaEditField.Limits = [0 1];
            app.SpotAlphaEditField.Value = 0.8;
            app.SpotLineEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.SpotLineEditFieldLabel.Text = 'Spot Line';
            app.SpotLineEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.SpotLineEditField.Limits = [0 100];
            app.SpotLineEditField.Value = 0.08;
            app.XEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.XEditField.Limits = [-100 100];
            app.XEditField.Tooltip = {'X position in degrees from the center of the screen'};
            app.YEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.YEditField.Limits = [-100 100];
            app.YEditField.Tooltip = {'Y position in degrees from the center of the screen'};
            app.GraceTimeEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.GraceTimeEditFieldLabel.Text = 'GraceTime';
            app.GraceTimeEditField = uieditfield(app.EyetrackersettingsPanel, 'numeric');
            app.GraceTimeEditField.Limits = [0 100];
            app.GraceTimeEditField.RoundFractionalValues = 'on';
            app.GraceTimeEditField.ValueDisplayFormat = '%.0f';
            app.GraceTimeEditField.Tooltip = {'The time the monkey has to move their eyes before the fixation window is enforced suring stimulus presentation'; ''};
            app.RADIUSEditFieldLabel = uilabel(app.EyetrackersettingsPanel);
            app.RADIUSEditFieldLabel.Text = 'RADIUS';
            app.RadiusEditField = uieditfield(app.EyetrackersettingsPanel, 'text');
            app.RadiusEditField.Tooltip = {'Radius of fixation window'};
            app.RadiusEditField.Value = '2';
            app.DrawEyeDropDownLabel = uilabel(app.EyetrackersettingsPanel);
            app.DrawEyeDropDownLabel.Text = 'Draw Eye?';
            app.DrawEyeDropDown = uidropdown(app.EyetrackersettingsPanel);
            app.DrawEyeDropDown.Items = {'No', 'On screen', 'New Screen'};
            app.DrawEyeDropDown.Value = 'New Screen';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.Data1Axes = uiaxes(app.PlotsTab);
            title(app.Data1Axes, 'Raw Data')
            xlabel(app.Data1Axes, 'Trial')
            ylabel(app.Data1Axes, 'Stimulus Value')
            app.Data2Axes = uiaxes(app.PlotsTab);
            title(app.Data2Axes, 'PF')
            xlabel(app.Data2Axes, 'Stimulus Value')
            ylabel(app.Data2Axes, 'Choice Probability')
            app.Data4Axes = uiaxes(app.PlotsTab);
            title(app.Data4Axes, 'Posterior')
            xlabel(app.Data4Axes, 'beta')
            ylabel(app.Data4Axes, 'alpha')
            app.Data3Axes = uiaxes(app.PlotsTab);
            title(app.Data3Axes, 'Posterior')
            xlabel(app.Data3Axes, 'beta')
            ylabel(app.Data3Axes, 'alpha')
            % Show the figure after all components are created
            app.MaskedAfterImageTestsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = behaviouralAcuity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskedAfterImageTestsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskedAfterImageTestsUIFigure)
        end
    end
end