classdef loadEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COGEEGAnalysisUIFigure    matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DataChsEditFieldLabel     matlab.ui.control.Label
        DataChsEditField          matlab.ui.control.EditField
        TriggerChsEditFieldLabel  matlab.ui.control.Label
        TriggerChsEditField       matlab.ui.control.EditField
        DFTFilterSwitchLabel      matlab.ui.control.Label
        DFTFilterSwitch           matlab.ui.control.Switch
        DemeanSwitchLabel         matlab.ui.control.Label
        DemeanSwitch              matlab.ui.control.Switch
        DetrendSwitchLabel        matlab.ui.control.Label
        DetrendSwitch             matlab.ui.control.Switch
        PolyRemovalSwitchLabel    matlab.ui.control.Label
        PolyRemovalSwitch         matlab.ui.control.Switch
        LoadDataButton            matlab.ui.control.Button
        EDFFile                   matlab.ui.control.TextArea
        BaselineEditFieldLabel    matlab.ui.control.Label
        BaselineEditField         matlab.ui.control.EditField
        FreqRangeEditFieldLabel   matlab.ui.control.Label
        FreqRangeEditField        matlab.ui.control.EditField
        ShowTriggersCheckBox      matlab.ui.control.CheckBox
        MATFile                   matlab.ui.control.TextArea
        RUNButton                 matlab.ui.control.Button
        KeepTrialsCheckBox        matlab.ui.control.CheckBox
        PlotRangeEditFieldLabel   matlab.ui.control.Label
        PlotRangeEditField        matlab.ui.control.EditField
        UITable                   matlab.ui.control.Table
        MinTriggerEditFieldLabel  matlab.ui.control.Label
        MinTriggerEditField       matlab.ui.control.NumericEditField
        JitterEditFieldLabel      matlab.ui.control.Label
        JitterEditField           matlab.ui.control.NumericEditField
        PreTimeEditFieldLabel     matlab.ui.control.Label
        PreTimeEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel   matlab.ui.control.Label
        ThresholdEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ana % data to send for analysis
    end
    
    methods (Access = private)
        
        function getValues(app)
            app.ana = [];
            app.ana.EDFFile = app.EDFFile.Value;
            app.ana.MATFile = app.MATFile.Value;
            if iscell(app.ana.EDFFile); app.ana.EDFFile = app.ana.EDFFile{1}; end
            if iscell(app.ana.MATFile); app.ana.MATFile = app.ana.MATFile{1}; end
            app.ana.plotTriggers = logical(app.ShowTriggersCheckBox.Value);
            app.ana.minTrigger = app.MinTriggerEditField.Value;
            app.ana.jitter = app.JitterEditField.Value;
            app.ana.preTime = app.PreTimeEditField.Value;
            app.ana.threshold = app.ThresholdEditField.Value;
            
            if strcmpi(app.DFTFilterSwitch.Value,'on')
                app.ana.dftfilter = 'yes';
            else
                app.ana.dftfilter = 'no';
            end
            if strcmpi(app.DemeanSwitch.Value,'on')
                app.ana.demean = 'yes';
            else
                app.ana.demean = 'no';
            end
            if strcmpi(app.DetrendSwitch.Value,'on')
                app.ana.detrend = 'yes';
            else
                app.ana.detrend = 'no';
            end
            if strcmpi(app.PolyRemovalSwitch.Value,'on')
                app.ana.polyremoval = 'yes';
            else
                app.ana.polyremoval = 'no';
            end

            app.ana.dataChannels = str2num(app.DataChsEditField.Value);
            app.ana.bitChannels = str2num(app.TriggerChsEditField.Value);
            app.ana.baseline = str2num(app.BaselineEditField.Value);
            app.ana.freqrange = str2num(app.FreqRangeEditField.Value);
            app.ana.latency = str2num(app.PlotRangeEditField.Value);
            app.ana.keeptrials = logical(app.KeepTrialsCheckBox.Value);
            app.ana.table = app.UITable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            r = groot;
            app.COGEEGAnalysisUIFigure.Position(1) = r.ScreenSize(3) - app.COGEEGAnalysisUIFigure.Position(3);
            app.COGEEGAnalysisUIFigure.Position(2) = r.ScreenSize(4) - app.COGEEGAnalysisUIFigure.Position(4);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [f,p] = uigetfile("*.edf",'Load EDF File');
            if ischar(f)
                app.EDFFile.Value = [p f];
            end
            [f,p] = uigetfile("*.mat",'Load Associated MAT File');
            if ischar(f)
                app.MATFile.Value = [p f];
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            getValues(app);
            runEEGAnalysis(app.ana);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COGEEGAnalysisUIFigure = uifigure('Visible', 'off');
            app.COGEEGAnalysisUIFigure.Name = 'COG EEG Analysis';
            app.GridLayout = uigridlayout(app.COGEEGAnalysisUIFigure);
            app.DataChsEditFieldLabel = uilabel(app.GridLayout);
            app.DataChsEditFieldLabel.Text = {'Data Chs'; ''};
            app.DataChsEditField = uieditfield(app.GridLayout, 'text');
            app.DataChsEditField.Value = '1:2';
            app.TriggerChsEditFieldLabel = uilabel(app.GridLayout);
            app.TriggerChsEditFieldLabel.Text = 'Trigger Chs';
            app.TriggerChsEditField = uieditfield(app.GridLayout, 'text');
            app.TriggerChsEditField.Value = '3:10';
            app.DFTFilterSwitchLabel = uilabel(app.GridLayout);
            app.DFTFilterSwitchLabel.Text = 'DFT Filter?';
            app.DFTFilterSwitch = uiswitch(app.GridLayout, 'slider');
            app.DFTFilterSwitch.Value = 'On';
            app.DemeanSwitchLabel = uilabel(app.GridLayout);
            app.DemeanSwitchLabel.Text = 'Demean?';
            app.DemeanSwitch = uiswitch(app.GridLayout, 'slider');
            app.DemeanSwitch.Value = 'On';
            app.DetrendSwitchLabel = uilabel(app.GridLayout);
            app.DetrendSwitchLabel.Text = {'Detrend?'; ''};
            app.DetrendSwitch = uiswitch(app.GridLayout, 'slider');
            app.DetrendSwitch.Value = 'On';
            app.PolyRemovalSwitchLabel = uilabel(app.GridLayout);
            app.PolyRemovalSwitchLabel.Text = 'Poly Removal?';
            app.PolyRemovalSwitch = uiswitch(app.GridLayout, 'slider');
            app.PolyRemovalSwitch.Value = 'On';
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.EDFFile = uitextarea(app.GridLayout);
            app.BaselineEditFieldLabel = uilabel(app.GridLayout);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.GridLayout, 'text');
            app.BaselineEditField.Value = '-0.1 0.1';
            app.FreqRangeEditFieldLabel = uilabel(app.GridLayout);
            app.FreqRangeEditFieldLabel.Text = 'Freq Range';
            app.FreqRangeEditField = uieditfield(app.GridLayout, 'text');
            app.FreqRangeEditField.Value = '2:2:40';
            app.ShowTriggersCheckBox = uicheckbox(app.GridLayout);
            app.ShowTriggersCheckBox.Text = 'Show Triggers?';
            app.MATFile = uitextarea(app.GridLayout);
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = 'start.png';
            app.RUNButton.Text = 'RUN!';
            app.KeepTrialsCheckBox = uicheckbox(app.GridLayout);
            app.KeepTrialsCheckBox.Text = 'Keep Trials?';
            app.PlotRangeEditFieldLabel = uilabel(app.GridLayout);
            app.PlotRangeEditFieldLabel.Text = 'Plot Range';
            app.PlotRangeEditField = uieditfield(app.GridLayout, 'text');
            app.PlotRangeEditField.Value = '-inf inf';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Trig A'; 'Trig B'; 'Stimuli'; 'Number'};
            app.UITable.RowName = {};
            app.MinTriggerEditFieldLabel = uilabel(app.GridLayout);
            app.MinTriggerEditFieldLabel.Text = 'Min Trigger';
            app.MinTriggerEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinTriggerEditField.Limits = [1 100];
            app.MinTriggerEditField.RoundFractionalValues = 'on';
            app.MinTriggerEditField.Value = 10;
            app.JitterEditFieldLabel = uilabel(app.GridLayout);
            app.JitterEditFieldLabel.Text = 'Jitter';
            app.JitterEditField = uieditfield(app.GridLayout, 'numeric');
            app.JitterEditField.Limits = [1 100];
            app.JitterEditField.RoundFractionalValues = 'on';
            app.JitterEditField.Value = 3;
            app.PreTimeEditFieldLabel = uilabel(app.GridLayout);
            app.PreTimeEditFieldLabel.Text = 'Pre Time';
            app.PreTimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.PreTimeEditField.Limits = [0 100];
            app.PreTimeEditField.Value = 0.5;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout, 'text');
            app.ThresholdEditField.Value = '6 * nanmedian';
            % Show the figure after all components are created
            app.COGEEGAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadEEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COGEEGAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COGEEGAnalysisUIFigure)
        end
    end
end